classdef CeldaLeme < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CeldaCargaFigure           matlab.ui.Figure
        btn_imgHelp                matlab.ui.control.Image
        img_Logo                   matlab.ui.control.Image
        tab_TipoConexion           matlab.ui.container.TabGroup
        ConexionSerialTab          matlab.ui.container.Tab
        dd_serialPort              matlab.ui.control.DropDown
        lbl_serialPort             matlab.ui.control.Label
        btn_UpdateSerialPort       matlab.ui.control.Button
        ef_serialBPS               matlab.ui.control.NumericEditField
        lbl_serialBPS              matlab.ui.control.Label
        btn_serialConnect          matlab.ui.control.Button
        tab_Ajustes                matlab.ui.container.TabGroup
        tab_AjusteCalibracion      matlab.ui.container.Tab
        ef_valCalManual            matlab.ui.control.NumericEditField
        ValordeCalibracinEditFieldLabel  matlab.ui.control.Label
        btn_readValPesoConocido    matlab.ui.control.Button
        btn_readValCalManual       matlab.ui.control.Button
        btn_doCalManual            matlab.ui.control.Button
        ef_valPesoConocido         matlab.ui.control.NumericEditField
        PesoConocidokgLabel        matlab.ui.control.Label
        btn_uploadPesoConocido     matlab.ui.control.Button
        btn_doCalAuto              matlab.ui.control.Button
        CalibracionAutomticaLabel  matlab.ui.control.Label
        CalibracinoManualLabel     matlab.ui.control.Label
        lbl_NombreArchivo          matlab.ui.control.Label
        btn_abrirArchivoMAT        matlab.ui.control.Button
        btn_ExportMAT              matlab.ui.control.Button
        btn_ExportCSV              matlab.ui.control.Button
        btn_initRecord             matlab.ui.control.Button
        btn_stopRecord             matlab.ui.control.Button
        FuerzakgLabel              matlab.ui.control.Label
        lamp_reading               matlab.ui.control.Lamp
        lbl_valFuerzaKg            matlab.ui.control.Label
        medidor_KG                 matlab.ui.control.Gauge
        btn_doTara                 matlab.ui.control.Button
        graph_FuerzaKg             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serial;
        buttons_lock;
        reading;
        raw;
        vect_time;
    end


    methods (Access = private)

        function plotFigures(app)
            if(app.buttons_lock)
                enableButtons(app);
                app.buttons_lock = 0;
            end

            bin2volt = @(raw, yoff, ymult, yzero)( (raw - yoff) .* ymult + yzero);

            
            % --------------------------------------------------------------------------------------------------------------------------> Canal 1
            % Si el CH1 esta habilitado, creo los vectores correspondientes
            if(strcmp(app.sw_ch1Enable.Value, "On"))
                app.computed_data.ch1 = bin2volt(app.raw_data.ch1, app.raw_data.ch1_off, app.raw_data.ch1_mult, app.raw_data.ch1_zero); % Volts
            else
                app.computed_data.ch1 = app.raw_data.ch1; % Volts en Cero
            end
            app.computed_data.t = (0:app.raw_data.dt:(length(app.raw_data.ch1) - 1) * app.raw_data.dt)' - app.raw_data.trig_pos;  % Tiempo
            plot(app.graph_FuerzaKg, (app.computed_data.t ./ app.factor_t) - app.offset_t ,  app.computed_data.ch1 .* app.factor_ch1);
            grid(app.graph_FuerzaKg, "on");
            % -----------------------------------------------------------------------------------------------------------------------------------


            % --------------------------------------------------------------------------------------------------------------------------> Canal 2
            % Si el CH1 esta habilitado, creo los vectores correspondientes
            if(strcmp(app.sw_ch2Enable.Value, "On"))
                app.computed_data.ch2 = bin2volt(app.raw_data.ch2, app.raw_data.ch2_off, app.raw_data.ch2_mult, app.raw_data.ch2_zero); % Volts
            else
                app.computed_data.ch2 = app.raw_data.ch2; % Volts en Cero
            end
            app.computed_data.t = (0:app.raw_data.dt:(length(app.raw_data.ch2) - 1) * app.raw_data.dt)' - app.raw_data.trig_pos;  % Tiempo
            plot(app.graph_CH2, (app.computed_data.t ./ app.factor_t) - app.offset_t ,  app.computed_data.ch2 .* app.factor_ch2);  % Plot
            grid(app.graph_CH2, "on");
            % -----------------------------------------------------------------------------------------------------------------------------------
                
            
            % Segun la unidad de tiempo seleccionada, cambio la leyenda
            switch(app.factor_t)
                case 1e-12
                    unit_t = 'ps';
                case 1e-9
                    unit_t = 'ns';
                case 0.000001
                    unit_t = 'µs';
                case 0.001
                    unit_t = 'ms';
                otherwise
                    unit_t = 's';
            end

            xlabel(app.graph_FuerzaKg, sprintf("Tiempo [%s]", unit_t));
            xlabel(app.graph_CH2, sprintf("Tiempo [%s]", unit_t));

            app.ef_valPretrigger.Value = app.raw_data.trig_pos ./ app.factor_t;
            app.lbl_valPretrigger.Text = sprintf("Pretriger [%s]", unit_t);


        end  

        function updateValuesInBoxs(app)
            % app.ef_startTime.Value = min(app.computed_data.t);  %Tiempo de inicio
            % app.ef_endTime.Value = max(app.computed_data.t);    %Tiempo final
            % app.ef_ch1YMin.Value = app.graph_FuerzaKg.YLim(1);       %CH1 Ymin
            % app.ef_ch1YMax.Value = app.graph_FuerzaKg.YLim(2);       %CH1 Ymax
            % app.ef_ch2YMin.Value = app.graph_CH2.YLim(1);       %CH2 YMin
            % app.ef_ch2YMax.Value = app.graph_CH2.YLim(2);       %CH2 YmaX
            % app.ef_ch1Factor.Value = app.factor_ch1;            %Factor CH1
            % app.ef_ch2Factor.Value = app.factor_ch2;            %Factor CH2
        end

        function disableButtons(app)
            % Conexión Serial (Lo deshabilito)
            app.dd_serialPort.Enable = 'on';
            app.ef_serialBPS.Enable = 'on';
            app.btn_UpdateSerialPort.Enable = 'on';
            
            % Box: Ajustes
            app.ef_valCalManual.Enable = 'off';
            app.btn_readValCalManual.Enable = 'off';
            app.btn_doCalManual.Enable = 'off';

            app.ef_valPesoConocido.Enable = 'off';
            app.btn_readValPesoConocido.Enable = 'off';
            app.btn_uploadPesoConocido.Enable = 'off';
            app.btn_doCalAuto.Enable = 'off';

            % Box: Tara
            app.btn_doTara.Enable = 'off';

            % Botones de export/import
            app.btn_abrirArchivoMAT.Enable = 'off';
            app.btn_ExportMAT.Enable = 'off';
            app.btn_ExportCSV.Enable = 'off';
        end

        function enableButtons(app)
            % Conexión Serial (Lo deshabilito)
            app.dd_serialPort.Enable = 'off';
            app.ef_serialBPS.Enable = 'off';
            app.btn_UpdateSerialPort.Enable = 'off';
            
            % Box: Ajustes
            app.ef_valCalManual.Enable = 'on';
            app.btn_readValCalManual.Enable = 'on';
            app.btn_doCalManual.Enable = 'on';

            app.ef_valPesoConocido.Enable = 'on';
            app.btn_readValPesoConocido.Enable = 'on';
            app.btn_uploadPesoConocido.Enable = 'on';
            app.btn_doCalAuto.Enable = 'on';

            % Box: Tara
            app.btn_doTara.Enable = 'on';

            % Botones de export/import
            % app.btn_abrirArchivoMAT.Enable = 'on';
            % app.btn_ExportMAT.Enable = 'on';
            % app.btn_ExportCSV.Enable = 'on';
        end

        function disableButtonsInRecord(app)          
            % Box: Ajustes
            app.ef_valCalManual.Enable = 'off';
            app.btn_readValCalManual.Enable = 'off';
            app.btn_doCalManual.Enable = 'off';

            app.ef_valPesoConocido.Enable = 'off';
            app.btn_readValPesoConocido.Enable = 'off';
            app.btn_uploadPesoConocido.Enable = 'off';
            app.btn_doCalAuto.Enable = 'off';

            % Box: Tara
            app.btn_doTara.Enable = 'off';

            % Botones de export/import
            app.btn_abrirArchivoMAT.Enable = 'off';
            app.btn_ExportMAT.Enable = 'off';
            app.btn_ExportCSV.Enable = 'off';
        end

        function enableButtonsInRecord(app)            
            % Box: Ajustes
            app.ef_valCalManual.Enable = 'on';
            app.btn_readValCalManual.Enable = 'on';
            app.btn_doCalManual.Enable = 'on';

            app.ef_valPesoConocido.Enable = 'on';
            app.btn_readValPesoConocido.Enable = 'on';
            app.btn_uploadPesoConocido.Enable = 'on';
            app.btn_doCalAuto.Enable = 'on';

            % Box: Tara
            app.btn_doTara.Enable = 'on';

            % Botones de export/import
            % app.btn_abrirArchivoMAT.Enable = 'on';
            % app.btn_ExportMAT.Enable = 'on';
            % app.btn_ExportCSV.Enable = 'on';
        end


        function serialCallback(app, src, ~)
            if(app.reading == 1)
                try
                    raw = utilidades.processRawData(readline(src), 'kg');
                    if isnan(raw)
                        e.identifier = "SerialCallback:rawIsNaN";
                        e.message = "No se logro identificar el valor recibido!";
                        error(e);
                    end

                    app.lbl_valFuerzaKg.Text = string(raw);
                    app.medidor_KG.Value = raw;

                    app.vect_time = [app.vect_time, toc];
                    app.raw = [app.raw, raw];
                    plot(app.graph_FuerzaKg, app.vect_time, app.raw);
                catch e
                    disp(e);
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onLoad(app)
            app.buttons_lock = 1;
            app.reading = 0;
            disableButtons(app);
            app.dd_serialPort.Items = serialportlist;
        end

        % Button pushed function: btn_serialConnect
        function Serial_Connect(app, event)
            if(strcmp(app.btn_serialConnect.Text, "Conectar"))
                % ───────────────────────────────────────────────────────────────
                %               Función: Conectar-Serial
                % ───────────────────────────────────────────────────────────────
                d = uiprogressdlg(app.CeldaCargaFigure, Message="Abriendo el puerto COM...", Title="Conectando...", ShowPercentage="on");
                app.reading = 0;
                
                try
                    %Realizo la conexion
                    app.serial.connection = serialport(app.dd_serialPort.Value, app.ef_serialBPS.Value, "Timeout", 5);
                    configureTerminator(app.serial.connection, "CR");
                    configureCallback(app.serial.connection, "terminator", @(src, evt)serialCallback(app, src, evt));
                    
                    % ==> Limpio los Buffers
                    % -------------------> Mensaje del uiprogress
                    d.Value = 0.2;
                    d.Message = "Limpiando buffers...";
                    %-------------------------------------------] 
                    pause(2);
                    flush(app.serial.connection, "input");
                    flush(app.serial.connection, "output");
                    
                    % ==> Verifico el modelo (check -> ok)
                    % -------------------> Mensaje del uiprogress
                    d.Value = 0.6;
                    d.Message = "Verificando modelo...";
                    %-------------------------------------------] 
                    if ~utilidades.checkResponseFromSerial(app.serial.connection, "#check&", "#ok&")
                        e.identifier = 'SerialConnect:CantCheck';
                        e.message = 'Ocurrió un error al verificar el modelo del dispositivo!';
                        error(e);
                    end
                  
                    
                    % ==> Leo las configuraciones (2 valores float)
                    % -------------------> Mensaje del uiprogress
                    d.Value = 0.8;
                    d.Message = "Leyendo configuraciones...";
                    %-------------------------------------------] 
                    pause(1);
                    cte_calib = utilidades.getParamFloat(app.serial.connection, "#get-cte-calib&", "cte-calib");
                    peso_conocido = utilidades.getParamFloat(app.serial.connection, "#get-weight&", "weight");
                    if isnan(cte_calib) || isnan(peso_conocido)
                        e.identifier = 'SerialConnect:LoadConfigs';
                        e.message = 'Ocurrió un error al descargar los datos del modulo!';
                        error(e); 
                    end
                    app.ef_valCalManual.Value = cte_calib;
                    app.ef_valPesoConocido.Value = peso_conocido;

                    % ==> Finalizo el uialert, cambio el texto del boton y habilito todos los demas.
                    % -------------------> Mensaje del uiprogress
                    d.Value = 1;
                    d.Message = "Finalizando configuraciones...";
                    pause(0.2);
                    uialert(app.CeldaCargaFigure, "Conexión Establecida", "Conexion", Icon='success');
                    %-------------------------------------------] 
                    app.btn_serialConnect.Text = "Desconectar";
                    app.enableButtons();
                    
                catch ME
                    app.serial.connection = [];     %Close(serial)
                    app.disableButtons();
                    switch(ME.identifier)
                        case 'transportclients:string:timeoutToken'
                            uialert(app.CeldaCargaFigure, 'No se recibio respuesta del modulo', 'Error', Icon='error');
                        case 'serialport:serialport:ConnectionFailed'
                            uialert(app.CeldaCargaFigure, 'El puerto se encuentra en uso por otra aplicacion', 'Error', Icon='error');
                        otherwise
                            uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon='error');
                    end
                end

                close(d);
            else
                % ───────────────────────────────────────────────────────────────
                %               Función: Desconectar-Serial
                % ───────────────────────────────────────────────────────────────
                app.reading = 0;
                app.btn_serialConnect.Text = "Conectar";
                app.serial.connection = []; %Close(Serial)
                app.disableButtons();
            end
        end

        % Button pushed function: btn_ExportMAT
        function Export_MAT(app, event)
            % t = datetime;
            % t.Format = 'yyyy.MM.dd_HH.mm';
            % 
            % clear dt offset_t ch1 ch2 trig_pos;
            % try
            %     dt = app.raw_data.dt;
            %     offset_t = app.offset_t * app.factor_t; %#ok<*NASGU,*ADPROPLC> 
            %     trig_pos = app.raw_data.trig_pos;
            % 
            %     ch1.raw = app.raw_data.ch1;
            %     ch1.ymult = app.raw_data.ch1_mult;
            %     ch1.yoff = app.raw_data.ch1_off;
            %     ch1.yzero = app.raw_data.ch1_zero;
            %     ch1.factor = app.factor_ch1;
            %     ch1.titulo = app.ef_valCalManual.Value;
            %     ch1.leyenda = app.ef_ch1Etiqueta.Value; %#ok<*STRNU> 
            % 
            %     ch2.raw = app.raw_data.ch2;
            %     ch2.ymult = app.raw_data.ch2_mult;
            %     ch2.yoff = app.raw_data.ch2_off;
            %     ch2.yzero = app.raw_data.ch2_zero;
            %     ch2.factor = app.factor_ch2;
            %     ch2.titulo = app.ef_ch2Titulo.Value;
            %     ch2.leyenda = app.ef_ch2Etiqueta.Value;
            % 
            % 
            %     uisave({'dt', 'offset_t', 'trig_pos', 'ch1', 'ch2'}, string(t))
            % catch ME
            %     switch(ME.identifier)
            %         case 'MATLAB:structRefFromNonStruct'
            %             uialert(app.CeldaCargaFigure, 'Se debe primero cargar alguna matriz de datos para poder exportar', 'Error', Icon='error');
            %         otherwise
            %             uialert(app.CeldaCargaFigure, "[" + ME.identifier + "]: " + ME.message, "Error", Icon='error');
            %     end
            % end
            % clear dt ch1 ch2;
        end

        % Button pushed function: btn_ExportCSV
        function Export_CSV(app, event)
            % t = datetime;
            % t.Format = 'yyyy.MM.dd_HH.mm';
            % 
            % [filename,filepath] = uiputfile('*.csv', 'Elegir ubicacion', string(t) + ".csv");
            % if ischar(filename)
            %     mtx = [app.computed_data.t, app.computed_data.ch1, app.computed_data.ch2];
            %     T = array2table(mtx, 'VariableNames', {'Tiempo', 'CH1', 'CH2'});
            %     writetable(T, [filepath filename], Delimiter='tab');
            % end
        end

        % Button pushed function: btn_abrirArchivoMAT
        function Import_MAT(app, event)
            % %Solicito que se abra el explorador para seleccionar el archivo
            % %Especificamente con la extension .mat
            % [file, path] = uigetfile('*.mat');
            % 
            % if ~isequal(file,0)
            %     %Consulto si en el archivo, estan todas las variables que necesito.
            %     %Estas son: raw_ch1, raw_ch2, dt, factor_ch1, factor_ch2, raw_ch1_v_div y raw_ch2_v_div
            %     ruta = [path file];    
            %     info_var = ismember(who("-file", ruta), {'dt', 'offset_t', 'trig_pos', 'ch1', 'ch2'});
            %     if all(info_var) && length(info_var) == 5
            % 
	        %         %Si estan todas, las cargo y las almaceno en donde corresponde (variable y casilleros)
	        %         load(ruta, 'dt', 'offset_t', 'trig_pos', 'ch1', 'ch2');
            % 
            %         try
            %             % Actualizo las variables internas
            %             app.factor_t = 1;
            %             app.raw_data.dt = dt;
            %             app.offset_t = offset_t;
            %             app.raw_data.trig_pos = trig_pos;
            %             app.raw_data.ch1 = ch1.raw;
            %             app.raw_data.ch1_mult = ch1.ymult;
            %             app.raw_data.ch1_off = ch1.yoff;
            %             app.raw_data.ch1_zero = ch1.yzero;
            %             app.factor_ch1 = ch1.factor;
            %             app.raw_data.ch2 = ch2.raw;
            %             app.raw_data.ch2_mult = ch2.ymult;
            %             app.raw_data.ch2_off = ch2.yoff;
            %             app.raw_data.ch2_zero = ch2.yzero;
            %             app.factor_ch2 = ch2.factor;
            % 
            % 
            %             %Actualizo los campos disponibles para el usuario
            %             app.ef_ch1Factor.Value = ch1.factor;
            %             app.ef_valCalManual.Value = ch1.titulo;
            %             app.ef_ch1Etiqueta.Value = ch1.leyenda;
            %             app.ef_ch2Factor.Value = ch2.factor;
            %             app.ef_ch2Titulo.Value = ch2.titulo;
            %             app.ef_ch2Etiqueta.Value = ch2.leyenda;
            %             app.ef_offsetTime.Value = offset_t;
            %             ylabel(app.graph_FuerzaKg, ch1.leyenda);
            %             ylabel(app.graph_CH2, ch2.leyenda);
            %             title(app.graph_FuerzaKg, ch1.titulo);
            %             title(app.graph_CH2, ch2.titulo);
            % 
            % 
	        %             app.dd_factorTime.Value = "1"; %Selector en 'Segundos'
            % 
	        %             %Muestro el plot correspondiente
	        %             plotFigures(app);
	        %             updateValuesInBoxs(app);
            % 
	        %             %Muestro el nombre del archivo cargado
            %             uialert(app.CeldaCargaFigure, "Archivo Leido: <font color='green'>" + file + "</font>", 'Apertura de Archivo .MAT', Icon='success', Interpreter='html');
            % 
            %             %Habilito los botones
            %             enableButtons(app);
            %             app.lbl_NombreArchivo.Text = "Archivo: <font color='green'>" + file + "</font>";
            %             app.lbl_NombreArchivo.Visible = 'on';
            %         catch ME
            %             app.lbl_NombreArchivo.Visible = 'off';
            %             switch ME.identifier
            %                 case 'MATLAB:nonExistentField'
            %                     uialert(app.CeldaCargaFigure, 'Los datos no corresponden a los exportados por esta aplicacion!', 'Error', Icon='error');
            %                 otherwise
            %                     uialert(app.CeldaCargaFigure, "[" + ME.identifier + "]: " + ME.message, 'Error', Icon='error');
            %             end
            %         end
            %     else
	        %         uialert(app.CeldaCargaFigure, 'El archivo no contiene las variables necesarias para esta aplicacion!', 'Error al Importar', Icon='error');
            %     end
            % end

        end

        % Button pushed function: btn_UpdateSerialPort
        function Change_Serial_BPS(app, event)
            app.dd_serialPort.Items = serialportlist;
        end

        % Image clicked function: btn_imgHelp
        function Click_Help_Window(app, event)
            Ventana_Help;
        end

        % Close request function: CeldaCargaFigure
        function Close_App(app, event)
            delete(Ventana_Help);
            delete(app)
        end

        % Image clicked function: img_Logo
        function img_LogoImageClicked(app, event)
            web('https://leme.ing.unlp.edu.ar/');
        end

        % Button pushed function: btn_initRecord
        function btn_initRecordButtonPushed(app, event)
            try
                if ~utilidades.checkResponseFromSerial(app.serial.connection, "#start&", "#starting&")
                    e.identifier = "InitRecord:notVerifiedResponse";
                    e.message = "No se recibió la confirmacion de la toma de muestras!";
                    error(e);
                end
                app.raw = [];  app.vect_time = [];
                app.lamp_reading.Color = [0.00,1.00,0.00];  % Lamp en verde
                tic;
                flush(app.serial.connection, 'input');
                flush(app.serial.connection, 'output');
                app.reading = 1;
                app.disableButtonsInRecord();
            catch ME
                app.lamp_reading.Color = [0.85,0.33,0.10];  % Lamp en naranja
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_stopRecord
        function btn_stopRecordButtonPushed(app, event)
            try
                if ~utilidades.checkResponseFromSerial(app.serial.connection, "#stop&", "#stopping&")
                    e.identifier = "StopRecord:notVerifiedResponse";
                    e.message = "No se recibió la confirmación del módulo!";
                    error(e);
                end
                app.lamp_reading.Color = [0.85,0.33,0.10];  % Lamp en naranja
                app.reading = 0;
                app.enableButtonsInRecord();
                flush(app.serial.connection, 'input');
                flush(app.serial.connection, 'output');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_readValCalManual
        function btn_readValCalManualButtonPushed(app, event)
            try
                cte_calib = utilidades.getParamFloat(app.serial.connection, "#get-cte-calib&", "cte-calib");
                if isnan(cte_calib)
                    e.identifier = "readValCalManual:valNaN";
                    e.message = "No se obtuvo una respuesta válida!";
                    error(e);
                end
                
                app.ef_valCalManual.Value = cte_calib;
                uialert(app.CeldaCargaFigure, 'Se actualizó el valor correctamente!', 'Descarga', Icon = 'success');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_readValPesoConocido
        function btn_readValPesoConocidoButtonPushed(app, event)
            try
                cte_calib = utilidades.getParamFloat(app.serial.connection, "#get-weight&", "weight");
                if isnan(cte_calib)
                    e.identifier = "readValPeso:valNaN";
                    e.message = "No se obtuvo una respuesta válida!";
                    error(e);
                end
                
                app.ef_valPesoConocido.Value = cte_calib;
                uialert(app.CeldaCargaFigure, 'Se actualizó el valor correctamente!', 'Descarga', Icon = 'success');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_uploadPesoConocido
        function btn_uploadPesoConocidoButtonPushed(app, event)
            try
                str = "#set-weight$" + string(app.ef_valPesoConocido.Value) + "&";

                if ~utilidades.checkResponseFromSerial(app.serial.connection, str, "#saved-weight&")
                    e.identifier = "setPeso:notVerifiedResponse";
                    e.message = "Ocurrió un error al cargar el parametro!";
                    error(e);
                end
                
                uialert(app.CeldaCargaFigure, 'Peso conocido actualizado correctamente!', 'Descarga', Icon = 'success');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_doCalManual
        function btn_doCalManualButtonPushed(app, event)
            try
                str = "#set-cal$" + string(app.ef_valCalManual.Value) + "&";

                if ~utilidades.checkResponseFromSerial(app.serial.connection, str, "#saved-cte-calib&")
                    e.identifier = "doCalManual:notVerifiedResponse";
                    e.message = "Ocurrió un error al cargar el parametro!";
                    error(e);
                end
                
                uialert(app.CeldaCargaFigure, 'Valor de calibracion actualizado correctamente!', 'Descarga', Icon = 'success');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_doTara
        function btn_doTaraButtonPushed(app, event)
            try
                if ~utilidades.checkResponseFromSerial(app.serial.connection, "#set-tare&", "#tare-ok&")
                    e.identifier = "doTara:notVerifiedResponse";
                    e.message = "Fallo la comunicacion con el módulo. No se logró realizar la Tara!";
                    error(e);
                end
                
                uialert(app.CeldaCargaFigure, 'Tara realizada con éxito!', 'Tara', Icon = 'success');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
        end

        % Button pushed function: btn_doCalAuto
        function btn_doCalAutoButtonPushed(app, event)
            d = uiprogressdlg(app.CeldaCargaFigure, Message="Iniciando el proceso...", Title="Calibración Automática...", ShowPercentage="off");
           
            try
                % Inicio el proceso
                % -------------------> Mensaje del uiprogress
                d.Indeterminate = 'on';
                %-------------------------------------------] 
                if ~utilidades.checkResponseFromSerial(app.serial.connection, "#do-auto-cal&", "#cal-process&")
                    e.identifier = "doCalAuto:notVerifiedResponse";
                    e.message = "No se recibió el acuse de inicio del proceso de calibración!";
                    error(e);
                end
                
                tic;

                while true
                    if app.serial.connection.NumBytesAvailable > 0
                        data = readline(app.serial.connection);
                        disp(data);

                        % Verifico los mensajes fijos
                        if strcmp(data, "#cal-init&")
                            % -------------------> Mensaje del uiprogress
                            d.Indeterminate = 'on';
                            d.Message = "Realizando Calibración...";
                            %-------------------------------------------]
                        elseif strcmp(data, "#cal-end&")
                            break;
                        else
                            porcentaje = utilidades.processRawData(data, 'cal-time');
                            if isnan(porcentaje)
                                e.identifier = "doCalAuto:PorcentajeNAN";
                                e.message = "Ocurrió un error al procesar los datos en la aplicación!";
                                error(e);
                            end
                            
                            % -------------------> Mensaje del uiprogress
                            d.Value = porcentaje/100.0;
                            d.Message = "Coloca el peso conocido encima de la balanza antes de que se complete la barra de progreso.";
                            d.Indeterminate = 'off';
                            %-------------------------------------------] 
                        end 
                    end

                    % Con estas dos funciones puedo ver cuanto tarda el proceso completo
                    % para luego fijar el timeout
                    % disp(toc);
                    % pause(0.1);

                    if(toc > 30)
                        e.identifier = "doCalAuto:Timeout";
                        e.message = "La calibración finalizo con errores, reintenta nuevamente!";
                        error(e);
                    end
                end

                uialert(app.CeldaCargaFigure, 'Calibración automática realizada con éxito!', 'Calibración', Icon = 'success');
            catch ME
                uialert(app.CeldaCargaFigure, ME.message, 'Error', Icon = 'error');
            end
            close(d);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CeldaCargaFigure = uifigure('Visible', 'off');
            app.CeldaCargaFigure.Name = 'Adquisicion OSC01';
            app.CeldaCargaFigure.Icon = fullfile(pathToMLAPP, 'assets', 'icon.png');
            app.CeldaCargaFigure.CloseRequestFcn = createCallbackFcn(app, @Close_App, true);
            app.graph_FuerzaKg = uiaxes(app.CeldaCargaFigure);
            title(app.graph_FuerzaKg, 'Gráfica')
            xlabel(app.graph_FuerzaKg, 'Tiempo [s]')
            ylabel(app.graph_FuerzaKg, 'Fuerza [kg]')
            zlabel(app.graph_FuerzaKg, 'Z')
            app.btn_doTara = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_doTara.ButtonPushedFcn = createCallbackFcn(app, @btn_doTaraButtonPushed, true);
            app.btn_doTara.Text = 'TARA';
            app.medidor_KG = uigauge(app.CeldaCargaFigure, 'circular');
            app.medidor_KG.Limits = [0 1000];
            app.lbl_valFuerzaKg = uilabel(app.CeldaCargaFigure);
            app.lbl_valFuerzaKg.Text = '0.00';
            app.lamp_reading = uilamp(app.CeldaCargaFigure);
            app.FuerzakgLabel = uilabel(app.CeldaCargaFigure);
            app.FuerzakgLabel.Text = 'Fuerza [kg]:';
            app.btn_stopRecord = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_stopRecord.ButtonPushedFcn = createCallbackFcn(app, @btn_stopRecordButtonPushed, true);
            app.btn_stopRecord.Text = 'Detener registro';
            app.btn_initRecord = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_initRecord.ButtonPushedFcn = createCallbackFcn(app, @btn_initRecordButtonPushed, true);
            app.btn_initRecord.Text = 'Iniciar registro';
            app.btn_ExportCSV = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_ExportCSV.ButtonPushedFcn = createCallbackFcn(app, @Export_CSV, true);
            app.btn_ExportCSV.Text = 'Exportar en Archivo .CSV';
            app.btn_ExportMAT = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_ExportMAT.ButtonPushedFcn = createCallbackFcn(app, @Export_MAT, true);
            app.btn_ExportMAT.Tooltip = {'El formato .MAT guarda los metadatos correspondientes a la configuracion de las gráficas.'};
            app.btn_ExportMAT.Text = 'Exportar en Archivo .MAT';
            app.btn_abrirArchivoMAT = uibutton(app.CeldaCargaFigure, 'push');
            app.btn_abrirArchivoMAT.ButtonPushedFcn = createCallbackFcn(app, @Import_MAT, true);
            app.btn_abrirArchivoMAT.Text = 'Importar Archivo .MAT';
            app.lbl_NombreArchivo = uilabel(app.CeldaCargaFigure);
            app.lbl_NombreArchivo.Visible = 'off';
            app.tab_Ajustes = uitabgroup(app.CeldaCargaFigure);
            app.tab_AjusteCalibracion = uitab(app.tab_Ajustes);
            app.tab_AjusteCalibracion.Title = 'Calibración';
            app.CalibracinoManualLabel = uilabel(app.tab_AjusteCalibracion);
            app.CalibracinoManualLabel.Text = 'Calibración Manual:';
            app.CalibracionAutomticaLabel = uilabel(app.tab_AjusteCalibracion);
            app.CalibracionAutomticaLabel.Text = 'Calibración Automática:';
            app.btn_doCalAuto = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_doCalAuto.ButtonPushedFcn = createCallbackFcn(app, @btn_doCalAutoButtonPushed, true);
            app.btn_doCalAuto.Text = 'Iniciar Calibración';
            app.btn_uploadPesoConocido = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_uploadPesoConocido.ButtonPushedFcn = createCallbackFcn(app, @btn_uploadPesoConocidoButtonPushed, true);
            app.btn_uploadPesoConocido.Text = 'Cargar';
            app.PesoConocidokgLabel = uilabel(app.tab_AjusteCalibracion);
            app.PesoConocidokgLabel.Text = 'Peso Conocido [kg]:';
            app.ef_valPesoConocido = uieditfield(app.tab_AjusteCalibracion, 'numeric');
            app.ef_valPesoConocido.ValueDisplayFormat = '%.2f';
            app.ef_valPesoConocido.Value = 0.1;
            app.btn_doCalManual = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_doCalManual.ButtonPushedFcn = createCallbackFcn(app, @btn_doCalManualButtonPushed, true);
            app.btn_doCalManual.Text = 'Calibrar';
            app.btn_readValCalManual = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_readValCalManual.ButtonPushedFcn = createCallbackFcn(app, @btn_readValCalManualButtonPushed, true);
            app.btn_readValCalManual.Text = 'Leer';
            app.btn_readValPesoConocido = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_readValPesoConocido.ButtonPushedFcn = createCallbackFcn(app, @btn_readValPesoConocidoButtonPushed, true);
            app.btn_readValPesoConocido.Text = 'Leer';
            app.ValordeCalibracinEditFieldLabel = uilabel(app.tab_AjusteCalibracion);
            app.ValordeCalibracinEditFieldLabel.Text = 'Valor de Calibración:';
            app.ef_valCalManual = uieditfield(app.tab_AjusteCalibracion, 'numeric');
            app.ef_valCalManual.ValueDisplayFormat = '%.2f';
            app.ef_valCalManual.Value = 0.1;
            app.tab_TipoConexion = uitabgroup(app.CeldaCargaFigure);
            app.ConexionSerialTab = uitab(app.tab_TipoConexion);
            app.ConexionSerialTab.Title = 'Conexion Serial';
            app.btn_serialConnect = uibutton(app.ConexionSerialTab, 'push');
            app.btn_serialConnect.ButtonPushedFcn = createCallbackFcn(app, @Serial_Connect, true);
            app.btn_serialConnect.Text = 'Conectar';
            app.lbl_serialBPS = uilabel(app.ConexionSerialTab);
            app.lbl_serialBPS.Text = 'Velocidad:';
            app.ef_serialBPS = uieditfield(app.ConexionSerialTab, 'numeric');
            app.ef_serialBPS.ValueDisplayFormat = '%.0f';
            app.ef_serialBPS.Value = 57600;
            app.btn_UpdateSerialPort = uibutton(app.ConexionSerialTab, 'push');
            app.btn_UpdateSerialPort.ButtonPushedFcn = createCallbackFcn(app, @Change_Serial_BPS, true);
            app.btn_UpdateSerialPort.Text = 'Actualizar';
            app.lbl_serialPort = uilabel(app.ConexionSerialTab);
            app.lbl_serialPort.Text = 'Puerto COM:';
            app.dd_serialPort = uidropdown(app.ConexionSerialTab);
            app.img_Logo = uiimage(app.CeldaCargaFigure);
            app.img_Logo.ImageClickedFcn = createCallbackFcn(app, @img_LogoImageClicked, true);
            app.img_Logo.ImageSource = fullfile(pathToMLAPP, 'assets', 'Logo_LEME.png');
            app.btn_imgHelp = uiimage(app.CeldaCargaFigure);
            app.btn_imgHelp.ImageClickedFcn = createCallbackFcn(app, @Click_Help_Window, true);
            app.btn_imgHelp.ImageSource = fullfile(pathToMLAPP, 'assets', 'help-icon.png');
            % Show the figure after all components are created
            app.CeldaCargaFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeldaLeme

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CeldaCargaFigure)

                % Execute the startup function
                runStartupFcn(app, @onLoad)
            else

                % Focus the running singleton app
                figure(runningApp.CeldaCargaFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CeldaCargaFigure)
        end
    end
end